classdef Heart_Barras < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        EligeunaopcinLabel   matlab.ui.control.Label
        EnfermedadesCardiovascularesporGneroyEdadLabel  matlab.ui.control.Label
        aosenadelanteButton  matlab.ui.control.Button
        a60aosButton         matlab.ui.control.Button
        a45aosButton         matlab.ui.control.Button
        a30aosButton         matlab.ui.control.Button
        a18aosButton         matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Property % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
      
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
  

        end

        % Button pushed function: a18aosButton
        function a18aosButtonPushed(app, event)
            % Obtener las edades y géneros para el rango de 0 a 18 años

rangos_0_18_hombres = rangos{1, 1}; % Edades de hombres en el rango 0-18
rangos_0_18_mujeres = rangos{1, 2}; % Edades de mujeres en el rango 0-18

% Contar la cantidad de hombres y mujeres en cada grupo
cantidad_hombres_0_18 = numel(rangos_0_18_hombres);
cantidad_mujeres_0_18 = numel(rangos_0_18_mujeres);

% Definir los ejes X e Y
edades = {'0-18'};
cantidad_personas = [cantidad_hombres_0_18, cantidad_mujeres_0_18];

% Crear la gráfica de barras en el UIAxes de la app
bar(app.UIAxes, categorical(edades), cantidad_personas, 'stacked');
title(app.UIAxes, 'Cantidad de personas por género en el rango de 0 a 18 años');
xlabel(app.UIAxes, 'Rango de Edades');
ylabel(app.UIAxes, 'Cantidad de Personas');
legend(app.UIAxes, 'Hombres', 'Mujeres');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Edad')
            ylabel(app.UIAxes, 'E. Cardiovascular')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.a18aosButton = uibutton(app.UIFigure, 'push');
            app.a18aosButton.ButtonPushedFcn = createCallbackFcn(app, @a18aosButtonPushed, true);
            app.a18aosButton.Text = {'0 a 18 años'; ''};
            app.a30aosButton = uibutton(app.UIFigure, 'push');
            app.a30aosButton.Text = {'18 a 30 años'; ''};
            app.a45aosButton = uibutton(app.UIFigure, 'push');
            app.a45aosButton.Text = {'30 a 45 años'; ''};
            app.a60aosButton = uibutton(app.UIFigure, 'push');
            app.a60aosButton.Text = {'45 a 60 años'; ''};
            app.aosenadelanteButton = uibutton(app.UIFigure, 'push');
            app.aosenadelanteButton.Text = {'60 años en adelante'; ''};
            app.EnfermedadesCardiovascularesporGneroyEdadLabel = uilabel(app.UIFigure);
            app.EnfermedadesCardiovascularesporGneroyEdadLabel.Text = 'Enfermedades Cardiovasculares por Género y Edad';
            app.EligeunaopcinLabel = uilabel(app.UIFigure);
            app.EligeunaopcinLabel.Text = 'Elige una opción:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Heart_Barras

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end